classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ETab                            matlab.ui.container.Tab
        LoadPanel                       matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        DataFileDropDown                matlab.ui.control.DropDown
        ImportButton                    matlab.ui.control.Button
        fromDatePickerLabel             matlab.ui.control.Label
        fromDatePicker                  matlab.ui.control.DatePicker
        toDatePickerLabel               matlab.ui.control.Label
        toDatePicker                    matlab.ui.control.DatePicker
        GridPanel                       matlab.ui.container.Panel
        PmaxkWSpinnerLabel              matlab.ui.control.Label
        PmaxkWSpinner                   matlab.ui.control.Spinner
        IntervalDropDownLabel           matlab.ui.control.Label
        IntervalDropDown                matlab.ui.control.DropDown
        VoltageACSpinner_2Label         matlab.ui.control.Label
        VoltageACSpinner                matlab.ui.control.Spinner
        ImaxASpinnerLabel               matlab.ui.control.Label
        ImaxASpinner                    matlab.ui.control.Spinner
        BalancePanel                    matlab.ui.container.Panel
        TotalLoadMWhEditFieldLabel      matlab.ui.control.Label
        TotalLoadMWhEditField           matlab.ui.control.NumericEditField
        GridEnergyMWhEditFieldLabel     matlab.ui.control.Label
        GridEnergyMWhEditField          matlab.ui.control.NumericEditField
        BalanceMWhEditFieldLabel        matlab.ui.control.Label
        BalanceMWhEditField             matlab.ui.control.NumericEditField
        UIAxesLoad                      matlab.ui.control.UIAxes
        PVTab                           matlab.ui.container.Tab
        BESSTab                         matlab.ui.container.Tab
        BasicPanel                      matlab.ui.container.Panel
        DisChargeEfficiencySpinnerLabel  matlab.ui.control.Label
        DisChargeEfficiencySpinner      matlab.ui.control.Spinner
        SOCminSpinnerLabel              matlab.ui.control.Label
        SOCminSpinner                   matlab.ui.control.Spinner
        BattsizekWhEditFieldLabel       matlab.ui.control.Label
        BattsizekWhEditField            matlab.ui.control.NumericEditField
        MaxdischPkWEditFieldLabel       matlab.ui.control.Label
        MaxdischPkWEditField            matlab.ui.control.NumericEditField
        MitigatedaysSpinnerLabel        matlab.ui.control.Label
        MitigatedaysSpinner             matlab.ui.control.Spinner
        SOCmaxSpinnerLabel              matlab.ui.control.Label
        SOCmaxSpinner                   matlab.ui.control.Spinner
        SOCinitialSpinnerLabel          matlab.ui.control.Label
        SOCinitialSpinner               matlab.ui.control.Spinner
        GridmaxPowerspreadSpinnerLabel  matlab.ui.control.Label
        GridmaxPowerspreadSpinner       matlab.ui.control.Spinner
        xkWSpinnerLabel                 matlab.ui.control.Label
        xkWSpinner                      matlab.ui.control.Spinner
        Lamp                            matlab.ui.control.Lamp
        AdvancedPanel                   matlab.ui.container.Panel
        TestBessSizeButton              matlab.ui.control.Button
        NumberofcellsSpinnerLabel       matlab.ui.control.Label
        NumberofcellsSpinner            matlab.ui.control.Spinner
        BatteryVoltageSpinnerLabel      matlab.ui.control.Label
        BatteryVoltageSpinner           matlab.ui.control.Spinner
        selfdischargeSpinnerLabel       matlab.ui.control.Label
        selfdischargeSpinner            matlab.ui.control.Spinner
        IdischmaxSpinnerLabel           matlab.ui.control.Label
        IdischmaxSpinner                matlab.ui.control.Spinner
        IchargemaxSpinnerLabel          matlab.ui.control.Label
        IchargemaxSpinner               matlab.ui.control.Spinner
        BattcapacitykWhSpinnerLabel     matlab.ui.control.Label
        BattcapacitykWhSpinner          matlab.ui.control.Spinner
        ExcessenergyEditFieldLabel      matlab.ui.control.Label
        ExcessenergyEditField           matlab.ui.control.NumericEditField
        EnergyGridEditFieldLabel        matlab.ui.control.Label
        EnergyGridEditField             matlab.ui.control.NumericEditField
        EnergyPenalEditFieldLabel       matlab.ui.control.Label
        EnergyPenalEditField            matlab.ui.control.NumericEditField
        EnergyDischargeEditFieldLabel   matlab.ui.control.Label
        EnergyDischargeEditField        matlab.ui.control.NumericEditField
        EnergyChargeEditFieldLabel      matlab.ui.control.Label
        EnergyChargeEditField           matlab.ui.control.NumericEditField
        EnergyPVEditFieldLabel          matlab.ui.control.Label
        EnergyPVEditField               matlab.ui.control.NumericEditField
        EnergyConsumedEditFieldLabel    matlab.ui.control.Label
        EnergyConsumedEditField         matlab.ui.control.NumericEditField
        EnergyConsumed1EditFieldLabel   matlab.ui.control.Label
        EnergyConsumed1EditField        matlab.ui.control.NumericEditField
        UIAxesMitigate                  matlab.ui.control.UIAxes
        UIAxesSizePower                 matlab.ui.control.UIAxes
        UIAxesCharge                    matlab.ui.control.UIAxes
        UIAxesDischarge                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataLoaded = false;
        battCalculated = false;
        timestep = 0;
        interval = 0;
        DATA % Load data and time serie
        DATA_orig %original data serie 
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('.\src');
            addpath('.\gui');
            
            % read list of available data files
            listing = dir('./data/*.mat');                
            app.DataFileDropDown.Items = { listing(:).name };
            
            % init vars
            app.IntervalDropDownValueChanged(app);
        end

        % Callback function
        function ConsuptionDropDownValueChanged(app, event)
%             value = app.DataFileDropDown.Value;
            LoadButtonPushed(app, event);
            %TODO fill the list with files acomplishing specified format of consuption data
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadButton.BackgroundColor = 'r';
            app.dataLoaded = false;
            
            load( ['.\data\' app.DataFileDropDown.Value ], 'T');
            app.DATA_orig.time = T.data_time;
            app.DATA_orig.load = T.Power_actual;
            
            %% REFINE DATA
            % cut to start at given Grid interval
            % shift first time to begin of day
            s = dateshift( T.data_time(1), 'start', 'day' );
            % how many intervals fits in the beginning
            c = ceil( ( T.data_time(1) - s ) / app.interval  );
            % shift start to multiply of grid interval
            s = s + app.interval*c;
            
            % cut to end at given Grid interval
            % shift first time to begin of day
            e = dateshift( T.data_time(end), 'end', 'day' );
            % how many intervals fits in the end
            c = ceil( ( e - T.data_time(end) ) / app.interval );
            % shift start to multiply of grid interval
            e = e - app.interval*c;
           
            % make differences and select median
            app.timestep = median( T.data_time - circshift(T.data_time, 1) );
            % create homogenous timeserie
            app.DATA.time = ( s : app.timestep : e )';
            app.DATA.time(end) = []; %cut the last element to make timeserie fit to multiply of interval
            
            %recalculate to homogenous timeserie
            app.DATA.load = interp1( T .data_time, T.Power_actual, app.DATA.time );
                                    
            % set bounds to datetime pickers
            app.fromDatePicker.Limits = [ app.DATA.time(1), app.DATA.time(end)];
            app.toDatePicker.Limits = [ app.DATA.time(1), app.DATA.time(end) ];
            app.fromDatePicker.Value = app.DATA.time(1);
            app.toDatePicker.Value = app.DATA.time(end);
                        
            app.LoadButton.BackgroundColor = 'g';
            app.dataLoaded = true;
            
            CalculateLoadBalance(app, event); % calculate numbers
            CalculateBESSSize(app, event);
        end

        % Value changed function: DisChargeEfficiencySpinner, 
        % GridmaxPowerspreadSpinner, MitigatedaysSpinner, 
        % SOCinitialSpinner, SOCmaxSpinner, SOCminSpinner, 
        % xkWSpinner
        function CalculateBESSSize(app, event)
            app.Lamp.Color = 'r';
            cla(app.UIAxesSizePower);
            cla(app.UIAxesMitigate);
            
            % select given datetime bounds
            select = app.DATA.time >= app.fromDatePicker.Value & app.DATA.time < app.toDatePicker.Value;
            TMP.time = app.DATA.time(select);
            TMP.load = app.DATA.load(select);
            
            %spread spectrum for different grid max power
            spread_max = app.GridmaxPowerspreadSpinner.Value * app.xkWSpinner.Value;
            Pmax = app.PmaxkWSpinner.Value - spread_max : app.xkWSpinner.Value : app.PmaxkWSpinner.Value + spread_max;
            s = zeros(length(Pmax),1);
            
            for i = 1 : length(Pmax)
                % calculate energy sums  
                [ ~, SUMS ] = calculateBalance( TMP, app.timestep, Pmax(i), app.interval, app.DisChargeEfficiencySpinner.Value, ...
                    app.MitigatedaysSpinner.Value );  
                SUMS_max = max(SUMS);
                
                % calculate battery capactiy to cover energy needs                        
                s(i) = max( SUMS_max ) * (1+1-(app.SOCmaxSpinner.Value - app.SOCminSpinner.Value)); %battery capacity to cover all peaks
                
                % plot original power
                if Pmax(i) == app.PmaxkWSpinner.Value
                    plot(app.UIAxesMitigate, ...
                        app.MitigatedaysSpinner.Value/length(SUMS_max):app.MitigatedaysSpinner.Value/length(SUMS_max):app.MitigatedaysSpinner.Value, ...
                        SUMS_max);
                    xline(app.UIAxesSizePower, Pmax(i));
                    yline(app.UIAxesSizePower, s(i));
                    app.BattsizekWhEditField.Value = s(i);
                    % how fast need to discharge batt to cover max energy peak
                    app.MaxdischPkWEditField.Value = max( SUMS(:,1) ) * ( duration(1,0,0) / app.interval );                    
                end
            end
            
            % plot batt.size x grid max P
            plot(app.UIAxesSizePower,Pmax,s,'o');
                        
            app.Lamp.Color = 'g';
            
            % calculate max charge and discharge
%             app.MaxdischPkWEditField.Value = max( SUMS(:,1) ) * ( duration(1,0,0) / app.Grid.interval );
%             app.BattsizekWhEditField.Value = min( SUMS(:,1) ) * ( duration(1,0,0) / app.Grid.interval );

            
            % TBD show batery capacity usage during period
%             figure
%             plot(SUMS(:,app.Batt.capacity_idx))
        end

        % Button pushed function: TestBessSizeButton
        function TestBessSizeButtonPushed(app, event)
            G.VAC = app.VoltageACSpinner.Value;
            G.Pmax = app.PmaxkWSpinner.Value *1000;
            
            bat.capacity = app.BattcapacitykWhSpinner.Value;
            bat.ns = app.NumberofcellsSpinner.Value;
            bat.V_bat = app.BatteryVoltageSpinner.Value;  % (V)
            bat.SOCmax = bat.capacity*1000/G.VAC*bat.V_bat;
            bat.SOC_max = app.SOCmaxSpinner.Value;
            bat.SOC1 = app.SOCinitialSpinner.Value;   % initial SOC (can be 0.5)
            bat.SOC3 = app.SOCminSpinner.Value; % 0.25; % Min. SOC of the battery.
            bat.K = 0.95;    % Charging efficiency
            bat.D = 1.9e-6;  % Self dis-charging rate (h^-1)
            bat.SOC2 = bat.SOC_max;
    
            bat.I_dis_max = app.IdischmaxSpinner.Value; % Maximum discharge current [A]
            bat.I_cha_max = app.IchargemaxSpinner.Value;
            
            % select given datetime bounds
            select = app.DATA_orig.time >= app.fromDatePicker.Value & app.DATA_orig.time < app.toDatePicker.Value;
            TMP.time = app.DATA_orig.time(select);
            TMP.load = app.DATA_orig.load(select);
            
            
            [ Time, I_L, I_PV, I_Gridf, I_Excessf, Penalf, Errorf, SOCf, I_Dischargef, I_Chargef, EE, Grid_Max_Energy, Z, t ] ...
                = testBessSize(  TMP, 1, bat, G);
            
            app.ExcessenergyEditField.Value = (((sum(I_Excessf)*t)*G.VAC)/1000);        % kWh
            app.EnergyGridEditField.Value = ((sum(I_Gridf)*t)*G.VAC)/1000;               % kWh
            app.EnergyPenalEditField.Value = ((sum(Penalf)*t)*G.VAC)/1000;              % kWh
            app.EnergyDischargeEditField.Value = ((sum(I_Dischargef)*t)*G.VAC)/1000;     % kWh
            app.EnergyChargeEditField.Value = ((sum(I_Chargef)*t)*G.VAC)/1000;           % kWh
            app.EnergyPVEditField.Value = ((sum(I_PV)*t)*G.VAC)/1000;                    % kWh
            app.EnergyConsumedEditField.Value=((sum(I_L)*t)*G.VAC)/1000; 
            app.EnergyConsumed1EditField.Value = app.EnergyPVEditField.Value ...
                + app.EnergyDischargeEditField.Value + app.EnergyGridEditField.Value ...
                + app.EnergyPenalEditField.Value - app.ExcessenergyEditField.Value - app.EnergyChargeEditField.Value;

            
            %% PLOT
%             yyaxis left
%             plot(app.UIAxesTest, Time, I_L*0.23);
%             plot(app.UIAxesTest, Time, (I_Gridf-I_Excessf)*0.23, 'g-.');
%             plot(app.UIAxesTest, Time, (I_Gridf-I_Excessf+Penalf)*0.23, 'g');
%             legend(app.UIAxesTest, 'PL','PGid','Penal')
%             yyaxis right
% %             plot(app.UIAxesTest, Time, Errorf*100, 'ColorMode', [0.8 0.5 0.5]);
%             plot(app.UIAxesTest, Time, Errorf*100);
%             plot(app.UIAxesTest, Time, SOCf*100, 'k--');
%             legend(app.UIAxesTest, 'Err','SOC')
%             ylabel (app.UIAxesTest, 'Over limit interval / SOC (%)');
                        
            plot(app.UIAxesCharge, Time, I_Chargef);
            plot(app.UIAxesDischarge, Time, I_Dischargef);
            
            
            figure(4)
            plot(Time,I_L*0.23);
            hold on
            grid on
            plot(Time,(I_Gridf-I_Excessf)*0.23,'g-.');
            plot(Time,(I_Gridf-I_Excessf+Penalf)*0.23,'g');
            ylabel ('Power (kW)');
            yyaxis right
            plot(Time,Errorf*100,'Color',[0.8 0.5 0.5]);
            hold on
            plot(Time,SOCf*100,'k--');
            legend('PL','PGid','Penal','Err','SOC')
            ylabel ('Over limit interval / SOC (%)');


            figure(5)
            plot(Time, EE)
            hold on
            plot(Time, Grid_Max_Energy*ones(1,length(Time)));
            grid on 
            yyaxis right
            plot(Time, Z*100)
            plot(Time,Errorf*100,'Color',[0.8 0.5 0.5]);

        end

        % Value changed function: PmaxkWSpinner, fromDatePicker, 
        % toDatePicker
        function CalculateLoadBalance(app, event)
            if app.dataLoaded
                
                % calculate energy sums
                [ E_time, E_balance ] = calculateBalance( app.DATA, app.timestep, app.PmaxkWSpinner.Value, app.interval);
                
                % select given datetime bounds
%                 select = app.DATA.time >= app.fromDatePicker.Value & app.DATA.time < app.toDatePicker.Value;
                select = E_time >= app.fromDatePicker.Value & E_time < app.toDatePicker.Value;
                                
                % plot
                yyaxis( app.UIAxesLoad, "left");
                cla( app.UIAxesLoad );
                plot( app.UIAxesLoad, app.DATA.time, app.DATA.load );
%                 plot( app.UIAxesLoad, app.DATA.time(select), app.DATA.load(select) );
                yline( app.UIAxesLoad, app.PmaxkWSpinner.Value );
                ylabel( app.UIAxesLoad, 'Power [kW]');
                
                yyaxis( app.UIAxesLoad, "right");
                cla( app.UIAxesLoad );
                bar( app.UIAxesLoad, E_time(select), E_balance(select), 1, 'FaceColor','none', 'EdgeColor','green' );
                ylabel( app.UIAxesLoad, 'Energy [kWh]');
                legend( app.UIAxesLoad, 'Load', 'Grid max', 'Energy of interval');
                
                % recalculate E_balance and other numbers
                app.GridEnergyMWhEditField.Value = app.PmaxkWSpinner.Value / (duration(1,0,0) / app.interval) * length (E_time) / 1000;
                app.BalanceMWhEditField.Value = sum(E_balance) / 1000;
                app.TotalLoadMWhEditField.Value = app.GridEnergyMWhEditField.Value + app.BalanceMWhEditField.Value;
            end
        end

        % Callback function
        function DisChargeEfficiencySpinnerValueChanged(app, event)
            app.CalculateBESSSize(app, event);           
        end

        % Callback function
        function SOCSpinnerValueChanged(app, event)
            app.CalculateBESSSize(app, event);
        end

        % Value changed function: IntervalDropDown
        function IntervalDropDownValueChanged(app, event)
            app.interval = duration(0, str2double(app.IntervalDropDown.Value), 0);
            app.CalculateLoadBalance(event);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ETab = uitab(app.TabGroup);
            app.ETab.Title = 'E';
            app.LoadPanel = uipanel(app.ETab);
            app.LoadPanel.Title = 'Load';
            app.LoadButton = uibutton(app.LoadPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DataFileDropDown = uidropdown(app.LoadPanel);
            app.DataFileDropDown.Items = {};
            app.DataFileDropDown.Value = {};
            app.ImportButton = uibutton(app.LoadPanel, 'push');
            app.ImportButton.Text = 'Import';
            app.fromDatePickerLabel = uilabel(app.LoadPanel);
            app.fromDatePickerLabel.Text = 'from';
            app.fromDatePicker = uidatepicker(app.LoadPanel);
            app.fromDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.fromDatePicker.ValueChangedFcn = createCallbackFcn(app, @CalculateLoadBalance, true);
            app.fromDatePicker.Value = datetime([2020 1 1]);
            app.toDatePickerLabel = uilabel(app.LoadPanel);
            app.toDatePickerLabel.Text = 'to';
            app.toDatePicker = uidatepicker(app.LoadPanel);
            app.toDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.toDatePicker.ValueChangedFcn = createCallbackFcn(app, @CalculateLoadBalance, true);
            app.toDatePicker.Value = datetime([2020 1 3]);
            app.GridPanel = uipanel(app.ETab);
            app.GridPanel.Title = 'Grid';
            app.PmaxkWSpinnerLabel = uilabel(app.GridPanel);
            app.PmaxkWSpinnerLabel.Text = 'P max [kW]';
            app.PmaxkWSpinner = uispinner(app.GridPanel);
            app.PmaxkWSpinner.Step = 100;
            app.PmaxkWSpinner.Limits = [0 10000];
            app.PmaxkWSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateLoadBalance, true);
            app.PmaxkWSpinner.Tooltip = {'Grid maximum contracted power'};
            app.PmaxkWSpinner.Value = 600;
            app.IntervalDropDownLabel = uilabel(app.GridPanel);
            app.IntervalDropDownLabel.Text = 'Interval';
            app.IntervalDropDown = uidropdown(app.GridPanel);
            app.IntervalDropDown.Items = {'1 min', '15 min', '1 hour'};
            app.IntervalDropDown.ItemsData = {'1', '15', '60'};
            app.IntervalDropDown.ValueChangedFcn = createCallbackFcn(app, @IntervalDropDownValueChanged, true);
            app.IntervalDropDown.Tooltip = {'Grid contracted power measuring interval.'};
            app.IntervalDropDown.Value = '15';
            app.VoltageACSpinner_2Label = uilabel(app.GridPanel);
            app.VoltageACSpinner_2Label.Text = 'Voltage AC';
            app.VoltageACSpinner = uispinner(app.GridPanel);
            app.VoltageACSpinner.Value = 230;
            app.ImaxASpinnerLabel = uilabel(app.GridPanel);
            app.ImaxASpinnerLabel.Text = 'I max [A]';
            app.ImaxASpinner = uispinner(app.GridPanel);
            app.ImaxASpinner.Value = 25;
            app.BalancePanel = uipanel(app.ETab);
            app.BalancePanel.Title = 'Balance';
            app.TotalLoadMWhEditFieldLabel = uilabel(app.BalancePanel);
            app.TotalLoadMWhEditFieldLabel.Text = 'Total Load [MWh]';
            app.TotalLoadMWhEditField = uieditfield(app.BalancePanel, 'numeric');
            app.TotalLoadMWhEditField.Editable = 'off';
            app.GridEnergyMWhEditFieldLabel = uilabel(app.BalancePanel);
            app.GridEnergyMWhEditFieldLabel.Text = 'Grid Energy [MWh]';
            app.GridEnergyMWhEditField = uieditfield(app.BalancePanel, 'numeric');
            app.GridEnergyMWhEditField.Editable = 'off';
            app.BalanceMWhEditFieldLabel = uilabel(app.BalancePanel);
            app.BalanceMWhEditFieldLabel.Text = 'Balance [MWh]';
            app.BalanceMWhEditField = uieditfield(app.BalancePanel, 'numeric');
            app.BalanceMWhEditField.Editable = 'off';
            app.UIAxesLoad = uiaxes(app.ETab);
            xlabel(app.UIAxesLoad, 'Time')
            ylabel(app.UIAxesLoad, 'Power')
            zlabel(app.UIAxesLoad, 'Z')
            app.PVTab = uitab(app.TabGroup);
            app.PVTab.Title = 'PV';
            app.BESSTab = uitab(app.TabGroup);
            app.BESSTab.Title = 'BESS';
            app.BasicPanel = uipanel(app.BESSTab);
            app.BasicPanel.Title = 'Basic';
            app.DisChargeEfficiencySpinnerLabel = uilabel(app.BasicPanel);
            app.DisChargeEfficiencySpinnerLabel.Text = 'Dis/Charge Efficiency';
            app.DisChargeEfficiencySpinner = uispinner(app.BasicPanel);
            app.DisChargeEfficiencySpinner.Step = 0.01;
            app.DisChargeEfficiencySpinner.Limits = [0.5 1];
            app.DisChargeEfficiencySpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.DisChargeEfficiencySpinner.Tooltip = {'charge/discharge efficiency'};
            app.DisChargeEfficiencySpinner.Value = 0.95;
            app.SOCminSpinnerLabel = uilabel(app.BasicPanel);
            app.SOCminSpinnerLabel.Text = 'SOC min';
            app.SOCminSpinner = uispinner(app.BasicPanel);
            app.SOCminSpinner.Step = 0.1;
            app.SOCminSpinner.Limits = [0 1];
            app.SOCminSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.SOCminSpinner.Value = 0.2;
            app.BattsizekWhEditFieldLabel = uilabel(app.BasicPanel);
            app.BattsizekWhEditFieldLabel.Text = 'Batt. size [kWh]';
            app.BattsizekWhEditField = uieditfield(app.BasicPanel, 'numeric');
            app.MaxdischPkWEditFieldLabel = uilabel(app.BasicPanel);
            app.MaxdischPkWEditFieldLabel.Text = 'Max disch. P [kW]';
            app.MaxdischPkWEditField = uieditfield(app.BasicPanel, 'numeric');
            app.MitigatedaysSpinnerLabel = uilabel(app.BasicPanel);
            app.MitigatedaysSpinnerLabel.Text = 'Mitigate days';
            app.MitigatedaysSpinner = uispinner(app.BasicPanel);
            app.MitigatedaysSpinner.Limits = [1 7];
            app.MitigatedaysSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.MitigatedaysSpinner.Tooltip = {'how many consecutive days should battery hold'};
            app.MitigatedaysSpinner.Value = 1;
            app.SOCmaxSpinnerLabel = uilabel(app.BasicPanel);
            app.SOCmaxSpinnerLabel.Text = 'SOC max';
            app.SOCmaxSpinner = uispinner(app.BasicPanel);
            app.SOCmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.SOCmaxSpinner.Value = 1;
            app.SOCinitialSpinnerLabel = uilabel(app.BasicPanel);
            app.SOCinitialSpinnerLabel.Text = 'SOC initial';
            app.SOCinitialSpinner = uispinner(app.BasicPanel);
            app.SOCinitialSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.SOCinitialSpinner.Value = 1;
            app.GridmaxPowerspreadSpinnerLabel = uilabel(app.BasicPanel);
            app.GridmaxPowerspreadSpinnerLabel.Text = 'Grid max Power spread';
            app.GridmaxPowerspreadSpinner = uispinner(app.BasicPanel);
            app.GridmaxPowerspreadSpinner.Limits = [1 20];
            app.GridmaxPowerspreadSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.GridmaxPowerspreadSpinner.Value = 10;
            app.xkWSpinnerLabel = uilabel(app.BasicPanel);
            app.xkWSpinnerLabel.Text = 'x [kW]';
            app.xkWSpinner = uispinner(app.BasicPanel);
            app.xkWSpinner.Step = 10;
            app.xkWSpinner.Limits = [1 1000];
            app.xkWSpinner.ValueChangedFcn = createCallbackFcn(app, @CalculateBESSSize, true);
            app.xkWSpinner.Value = 10;
            app.Lamp = uilamp(app.BasicPanel);
            app.AdvancedPanel = uipanel(app.BESSTab);
            app.AdvancedPanel.Title = 'Advanced';
            app.TestBessSizeButton = uibutton(app.AdvancedPanel, 'push');
            app.TestBessSizeButton.ButtonPushedFcn = createCallbackFcn(app, @TestBessSizeButtonPushed, true);
            app.TestBessSizeButton.Text = 'Test Bess Size';
            app.NumberofcellsSpinnerLabel = uilabel(app.AdvancedPanel);
            app.NumberofcellsSpinnerLabel.Text = 'Number of cells';
            app.NumberofcellsSpinner = uispinner(app.AdvancedPanel);
            app.NumberofcellsSpinner.Value = 24;
            app.BatteryVoltageSpinnerLabel = uilabel(app.AdvancedPanel);
            app.BatteryVoltageSpinnerLabel.Text = 'Battery Voltage';
            app.BatteryVoltageSpinner = uispinner(app.AdvancedPanel);
            app.BatteryVoltageSpinner.Value = 48;
            app.selfdischargeSpinnerLabel = uilabel(app.AdvancedPanel);
            app.selfdischargeSpinnerLabel.Text = 'self discharge';
            app.selfdischargeSpinner = uispinner(app.AdvancedPanel);
            app.selfdischargeSpinner.Value = 1.9e-06;
            app.IdischmaxSpinnerLabel = uilabel(app.AdvancedPanel);
            app.IdischmaxSpinnerLabel.Text = 'I disch. max';
            app.IdischmaxSpinner = uispinner(app.AdvancedPanel);
            app.IdischmaxSpinner.Value = 3000;
            app.IchargemaxSpinnerLabel = uilabel(app.AdvancedPanel);
            app.IchargemaxSpinnerLabel.Text = 'I charge max';
            app.IchargemaxSpinner = uispinner(app.AdvancedPanel);
            app.IchargemaxSpinner.Value = 3000;
            app.BattcapacitykWhSpinnerLabel = uilabel(app.AdvancedPanel);
            app.BattcapacitykWhSpinnerLabel.Text = 'Batt. capacity [kWh]';
            app.BattcapacitykWhSpinner = uispinner(app.AdvancedPanel);
            app.BattcapacitykWhSpinner.Value = 681;
            app.ExcessenergyEditFieldLabel = uilabel(app.BESSTab);
            app.ExcessenergyEditFieldLabel.Text = 'Excess energy';
            app.ExcessenergyEditField = uieditfield(app.BESSTab, 'numeric');
            app.ExcessenergyEditField.Editable = 'off';
            app.EnergyGridEditFieldLabel = uilabel(app.BESSTab);
            app.EnergyGridEditFieldLabel.Text = 'Energy Grid';
            app.EnergyGridEditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyGridEditField.Editable = 'off';
            app.EnergyPenalEditFieldLabel = uilabel(app.BESSTab);
            app.EnergyPenalEditFieldLabel.Text = 'Energy Penal';
            app.EnergyPenalEditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyPenalEditField.Editable = 'off';
            app.EnergyDischargeEditFieldLabel = uilabel(app.BESSTab);
            app.EnergyDischargeEditFieldLabel.Text = 'Energy Discharge';
            app.EnergyDischargeEditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyDischargeEditField.Editable = 'off';
            app.EnergyChargeEditFieldLabel = uilabel(app.BESSTab);
            app.EnergyChargeEditFieldLabel.Text = 'Energy Charge';
            app.EnergyChargeEditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyChargeEditField.Editable = 'off';
            app.EnergyPVEditFieldLabel = uilabel(app.BESSTab);
            app.EnergyPVEditFieldLabel.Text = 'Energy PV';
            app.EnergyPVEditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyPVEditField.Editable = 'off';
            app.EnergyConsumedEditFieldLabel = uilabel(app.BESSTab);
            app.EnergyConsumedEditFieldLabel.Text = 'Energy Consumed';
            app.EnergyConsumedEditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyConsumedEditField.Editable = 'off';
            app.EnergyConsumed1EditFieldLabel = uilabel(app.BESSTab);
            app.EnergyConsumed1EditFieldLabel.Text = 'Energy Consumed 1';
            app.EnergyConsumed1EditField = uieditfield(app.BESSTab, 'numeric');
            app.EnergyConsumed1EditField.Editable = 'off';
            app.UIAxesMitigate = uiaxes(app.BESSTab);
            xlabel(app.UIAxesMitigate, 'Max. mitigated interval [days]')
            ylabel(app.UIAxesMitigate, 'Battery Size [kWh]')
            zlabel(app.UIAxesMitigate, 'Z')
            app.UIAxesSizePower = uiaxes(app.BESSTab);
            xlabel(app.UIAxesSizePower, 'Grid max Power [kW]')
            ylabel(app.UIAxesSizePower, 'Battery Size [kWh]')
            zlabel(app.UIAxesSizePower, 'Z')
            app.UIAxesCharge = uiaxes(app.BESSTab);
            title(app.UIAxesCharge, 'Charge')
            xlabel(app.UIAxesCharge, 'Time')
            ylabel(app.UIAxesCharge, 'Current [A]')
            zlabel(app.UIAxesCharge, 'Z')
            app.UIAxesDischarge = uiaxes(app.BESSTab);
            title(app.UIAxesDischarge, 'Discharge')
            xlabel(app.UIAxesDischarge, 'Time')
            ylabel(app.UIAxesDischarge, 'Current [A]')
            zlabel(app.UIAxesDischarge, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end